classdef GUIcomponents < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CardTable            matlab.ui.control.Image
        SettingsButton       matlab.ui.control.Image
        MapButton            matlab.ui.control.Image
        PlayerProfile        matlab.ui.control.Image
        TaskBarFiller        matlab.ui.control.Label
        TaskBarEmpty         matlab.ui.control.Image
        Deck                 matlab.ui.control.Image
        HelpButton           matlab.ui.control.Image
        PlayerTable1         matlab.ui.control.Image
        CardPile             matlab.ui.control.Image
        SYSTEMMESSAGESPanel  matlab.ui.container.Panel
        TextArea             matlab.ui.control.TextArea
        Shuffle              matlab.ui.control.Image
        PlayerTable1_2       matlab.ui.control.Image
        PlayerTable1_3       matlab.ui.control.Image
        PlayerTable1_4       matlab.ui.control.Image
        Task8                matlab.ui.control.Image
        Task7                matlab.ui.control.Image
        Task6                matlab.ui.control.Image
        Task5                matlab.ui.control.Image
        Task4                matlab.ui.control.Image
        Task3                matlab.ui.control.Image
        Task2                matlab.ui.control.Image
        Task1                matlab.ui.control.Image
        STARTSCREENPanel     matlab.ui.container.Panel
        startImage           matlab.ui.control.Image
        STARTButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        myPlayerNum    % Player 1 or Player 2?
        otherPlayerNum % Number of other player whose turn is after yours
        channelID      % ThingSpeak Channel ID
        writeKey       % Channel Write API Key
        readKey        % Channel Read API Key
        readDelay      % wait 5 seconds between ThingSpeak Channel Reads
        writeDelay     % wait 5 seconds between ThingSpeak Channel Writings
        taskDeck       % shuffle deck of task cards (no imposter cards in 2 player)
        myLastDrawnCard % card that this player most recently drew
        otherPlayerLastDrawnCard % card that the other player most recently drew
        currentHand    % max 8 cards in a hand
        
         
    end

    methods (Access = public)
        
        function app = setImages1(app)
            %if there are less than 8 cards in the hand (obv more than 5 given)
            %then make the spaces for 6 or 7 or 8 cards set to transparent
            if((app.player1.currentHand)<8)
                imageArray1 = repmat({'transparent.png'},1, 60);
                
            end
        end
        
        %clears any data from previous games in thingspeak channel before you
        %start a new game
        function [] = ClearThingSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values','WriteKey',app.writeKey);
        end
        
        
        function [] = ShuffleDeck(app)
            %randperm returns a row vector 
            %containing a random permutation of the integers from 1 to n
            app.taskDeck = app.taskDeck(randperm(length(app.taskDeck)));
            
        end
        function [] = ResetTaskDeck(app)
            app.taskDeck = [];
            for duplicates = 1:4
                for task = 1:15
                    card.duplicates = duplicates;
                    card.task = task;
                    card.val = 5;
                    app.taskDeck = [app.taskDeck,card];
                end
            end
            app.ShuffleDeck();
        end
        
        function [] = CreateTaskDeck(app)
            whole_array = cell(1,60);
            index_tasks_array = repmat({[1],[2],[3],[4],[5],[6],[7],[8]...
                ,[9],[10],[11],[12],[13],[14],[15]},1,4);
            index_array = {1:60};
            prefix_name = cell(1,60);
            imagename_array = cell(1,60);
            for i = 1:60
                prefix_name{i} = strjoin(['ProjTask', index_tasks_array(i)],'');
                imagename_array{i}= strjoin([prefix_name{i}, '.gif'],'');
            end
            app.taskDeck = struct('index', index_array, 'index tasks array', index_tasks_array, ...
                'whole_array', whole_array, 'image name', imagename_array);
        end
        
        function [drawnCard] = DrawCard(app)
            drawnCard = app.taskDeck(end);
            app.taskDeck(end)= [];
        end
        
        function [] = ClearPlayerCards(app)
            app.myLastDrawnCard.task = 0;
            app.otherPlayerLastDrawnCard.task = 0;
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STARTSCREENPanel.Visible = "on";
            %app.STARTSCREENPanel.Visible = "on";
            %app.STARTSCREENPanel.Visible = "on";
            
            %set(app.Player1RedButton,'Enable','off');
            %set(app.Player2BlueButton,'Enable','off');
            
            
            if(app.myPlayerNum == 1)
                set(app.DeckClicked, 'Enable','on');
                app.ResetTaskDeck();
            %app.myPlayerNum = 2
            else 
                app.WaitForOtherPlayer();
                app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                    app.GetCardName(app.otherPlayerLastDrawnCard));
                set(app.DeckClicked,'Enable','on');
            end
        end

        % Callback function
        function Task2ImageClicked(app, event)
            app.Task4.Position=[137 0 76 109];
        end

        % Image clicked function: Deck
        function DeckClicked(app, event)
            app.myLastDrawnCard = app.DrawCard();
            
            app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                app.GetCardName(app.otherPlayerLastDrawnCard));
            
            set(app.DrawButton,'Enable','off');
            
            app.SendDataToOtherPlayer();
            
            %At this point, player 1 is already displaying both cards 
            %so the cards variables can be set to blank
            if(app.myPlayerNum ==2)
                app.ClearPlayerCards();
            end
            app.waitForOtherPlayer();
            
            %At this point, player 1 is already displaying both cards 
            %so the cards variables can be set to blank
            if(app.myPlayerNum ==1)
                app.ClearPlayerCards();
            end
            app.waitForOtherPlayer();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTSCREENPanel.Visible = "off";
            msgbox("Welcome to the ship! You are a CREWMATE.");
            %set(app.STARTButton,'Enable','on');
            %set(app.STARTButton,'Enable','off');
        end

        % Image clicked function: Task4
        function Task4ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CardTable = uiimage(app.UIFigure);
            app.CardTable.ImageSource = 'CardTable.gif';
            app.SettingsButton = uiimage(app.UIFigure);
            app.SettingsButton.ImageSource = 'SettingsButton.gif';
            app.MapButton = uiimage(app.UIFigure);
            app.MapButton.ImageSource = 'MapButton.gif';
            app.PlayerProfile = uiimage(app.UIFigure);
            app.PlayerProfile.ImageSource = 'CrewPurple.gif';
            app.TaskBarFiller = uilabel(app.UIFigure);
            app.TaskBarEmpty = uiimage(app.UIFigure);
            app.TaskBarEmpty.ImageSource = 'TaskBarTransparent.gif';
            app.Deck = uiimage(app.UIFigure);
            app.Deck.ImageClickedFcn = createCallbackFcn(app, @DeckClicked, true);
            app.Deck.ImageSource = 'DeckPile.gif';
            app.HelpButton = uiimage(app.UIFigure);
            app.HelpButton.ImageSource = 'HelpButton.gif';
            app.PlayerTable1 = uiimage(app.UIFigure);
            app.PlayerTable1.ImageSource = 'CrewBlue.gif';
            app.CardPile = uiimage(app.UIFigure);
            app.CardPile.ImageSource = 'DeckCardsPlayed.gif';
            app.SYSTEMMESSAGESPanel = uipanel(app.UIFigure);
            app.SYSTEMMESSAGESPanel.Title = 'SYSTEM MESSAGES ';
            app.TextArea = uitextarea(app.SYSTEMMESSAGESPanel);
            app.TextArea.Value = {'Disaster!'};
            app.Shuffle = uiimage(app.UIFigure);
            app.Shuffle.ImageSource = 'ShuffleCard.gif';
            app.PlayerTable1_2 = uiimage(app.UIFigure);
            app.PlayerTable1_2.ImageSource = 'CrewPink.gif';
            app.PlayerTable1_3 = uiimage(app.UIFigure);
            app.PlayerTable1_3.ImageSource = 'CrewGreen.gif';
            app.PlayerTable1_4 = uiimage(app.UIFigure);
            app.PlayerTable1_4.ImageSource = 'CrewBrown.gif';
            app.Task8 = uiimage(app.UIFigure);
            app.Task8.ImageSource = 'ProjTask6.gif';
            app.Task7 = uiimage(app.UIFigure);
            app.Task7.ImageSource = 'ProjTask7.gif';
            app.Task6 = uiimage(app.UIFigure);
            app.Task6.ImageSource = 'ProjTask8.gif';
            app.Task5 = uiimage(app.UIFigure);
            app.Task5.ImageSource = 'ProjTask1.gif';
            app.Task4 = uiimage(app.UIFigure);
            app.Task4.ImageClickedFcn = createCallbackFcn(app, @Task4ImageClicked, true);
            app.Task4.ImageSource = 'ProjTask2.gif';
            app.Task3 = uiimage(app.UIFigure);
            app.Task3.ImageSource = 'ProjTask3.gif';
            app.Task2 = uiimage(app.UIFigure);
            app.Task2.ImageSource = 'ProjTask4.gif';
            app.Task1 = uiimage(app.UIFigure);
            app.Task1.ImageSource = 'ProjTask5.gif';
            app.STARTSCREENPanel = uipanel(app.UIFigure);
            app.STARTSCREENPanel.Title = 'START SCREEN';
            app.STARTSCREENPanel.Visible = 'off';
            app.startImage = uiimage(app.STARTSCREENPanel);
            app.startImage.ImageSource = 'AmongUsTitlePage.gif';
            app.STARTButton = uibutton(app.STARTSCREENPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIcomponents

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end